classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        AnalysisTargetGroup             matlab.ui.container.ButtonGroup
        PressurevsTemperatureButton     matlab.ui.control.RadioButton
        TemperaturevsTimeButton         matlab.ui.control.RadioButton
        PressurevsTimeButton            matlab.ui.control.RadioButton
        FileNameLabel                   matlab.ui.control.Label
        StandardizeDataButton           matlab.ui.control.Button
        SmoothingWindowSlider           matlab.ui.control.Slider
        SmoothingWindowLabel            matlab.ui.control.Label
        SmoothDataButton                matlab.ui.control.Button
        WinsorizeOutliersButton         matlab.ui.control.Button
        RemoveOutliersButton            matlab.ui.control.Button
        SensitivitySlider               matlab.ui.control.Slider
        MultiplierLabel                 matlab.ui.control.Label
        IdentifyOutliersButton          matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        BivariateAnalysisTab            matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PressureVsTempCorrelationLabel  matlab.ui.control.Label
        TimeSeriesCorrelationLabel      matlab.ui.control.Label
        PressureVsTempAxes              matlab.ui.control.UIAxes
        TimeSeriesAxes                  matlab.ui.control.UIAxes
        UnivariateAnalysisTab           matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PressureStatisticsLabel         matlab.ui.control.Label
        TemperatureStatisticsLabel      matlab.ui.control.Label
        PressureHistogramAxes           matlab.ui.control.UIAxes
        PressureBoxPlotAxes             matlab.ui.control.UIAxes
        TemperatureBoxPlotAxes          matlab.ui.control.UIAxes
        TemperatureHistogramAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        OutliersVisible = false; % Tracks if outliers are currently displayed
        TimeData = [];
        TemperatureData = [];
        PressureData = [];

        % Properties to store outlier information
        OutlierIndices = [];
        OutlierTargetData = ''; % 'Temperature', 'Pressure', or 'Both'
        OutlierThreshold = 0;
        OutlierModelFit = [];

        % Properties for data standardization
        PreStandardizedTemperatureData = [];
        PreStandardizedPressureData = [];
        IsDataStandardized = false;

        % Properties for data smoothing
        PreSmoothedTemperatureData = [];
        PreSmoothedPressureData = [];
        IsDataSmoothed = false;
    end
    
    methods (Access = private)
        % Main function to handle outlier detection and plotting (no changes needed here)
        function updateOutliers(app)
            if isempty(app.TemperatureData)
                return; % No data loaded
            end
            
            % First, clear any outliers that might exist on ANY plot
            app.hideOutliers();
            
            % Get the analysis target from the selected radio button
            target = app.AnalysisTargetGroup.SelectedObject.Text;
            
            % Get sensitivity
            std_multiplier = app.SensitivitySlider.Value;
            app.MultiplierLabel.Text = sprintf('Outlier Std Div: %.2f', std_multiplier);
            
            y_data = []; % Initialize y_data
            
            switch target
                case {'Temperature vs. Time', 'Pressure vs. Time'}
                    % LOGIC FOR TIME SERIES PLOTS
                    targetAxes = app.TimeSeriesAxes;
                    
                    if strcmp(target, 'Temperature vs. Time')
                        dataLine = findobj(targetAxes, 'DisplayName', 'Temperature');
                        y_axis_side = 'left';
                        app.OutlierTargetData = 'Temperature'; % Set target
                        y_data = app.TemperatureData;
                    else % Pressure vs. Time
                        dataLine = findobj(targetAxes, 'DisplayName', 'Pressure');
                        y_axis_side = 'right';
                        app.OutlierTargetData = 'Pressure'; % Set target
                        y_data = app.PressureData;
                    end
                    
                    if isempty(dataLine)
                        return; % Correct data line not found
                    end
                    x_data = dataLine.XData;
                    
                    % Use moving average for time series data
                    window_size = 20;
                    moving_avg = movmean(y_data, window_size);
                    residuals = y_data - moving_avg;
                    app.OutlierModelFit = moving_avg; % Store model fit
                    
                case 'Pressure vs. Temperature'
                    % LOGIC FOR SCATTER PLOT
                    targetAxes = app.PressureVsTempAxes;
                    x_data = app.TemperatureData;
                    y_data = app.PressureData;
                    app.OutlierTargetData = 'Both'; % Set target for both datasets
                    
                    % Use linear regression for scatter data
                    p = polyfit(x_data, y_data, 1);
                    y_fit = polyval(p, x_data);
                    residuals = y_data - y_fit;
                    app.OutlierModelFit = y_fit; % Store model fit
            end
            
            % COMMON LOGIC: Identify and plot outliers based on residuals
            residual_std = std(residuals);
            threshold = std_multiplier * residual_std;
            outlier_indices = find(abs(residuals) > threshold);
            
            % Store outlier information for other functions to use
            app.OutlierIndices = outlier_indices;
            app.OutlierThreshold = threshold;
            
            if ~isempty(outlier_indices)
                x_outliers = x_data(outlier_indices);
                y_outliers = y_data(outlier_indices);
                
                if isequal(targetAxes, app.TimeSeriesAxes)
                    yyaxis(targetAxes, y_axis_side);
                end
                
                hold(targetAxes, 'on');
                plot(targetAxes, x_outliers, y_outliers, 'ro', ...
                    'MarkerSize', 10, 'LineWidth', 2, ...
                    'DisplayName', 'Outliers', 'Tag', 'outlier_plot');
                hold(targetAxes, 'off');
            end
            
            legend(targetAxes, 'show');
        end

        % Function to specifically hide the outlier circles
        function hideOutliers(app)
            % Find ALL outlier plots in the entire figure and delete them
            previous_outliers = findobj(app.UIFigure, 'Tag', 'outlier_plot');
            if ~isempty(previous_outliers)
                delete(previous_outliers);
            end
            % Refresh legends on both axes to revert to their original state
            legend(app.TimeSeriesAxes, 'show');
            legend(app.PressureVsTempAxes, 'show');
        end
        
        % Function to hide all outlier-related UI controls
        function hideOutlierControls(app)
            app.IdentifyOutliersButton.Text = 'Identify Outliers';
            app.SensitivitySlider.Visible = 'off';
            app.MultiplierLabel.Visible = 'off';
            app.AnalysisTargetGroup.Visible = 'off';
            app.RemoveOutliersButton.Visible = 'off';
            app.WinsorizeOutliersButton.Visible = 'off';
            app.OutliersVisible = false;
        end

        % Refactored plotting and stats calculation into its own function
        function updateAllPlotsAndStats(app)
            % This function replots everything based on the current app data properties
            
            % Determine units for labels based on standardization state
            if app.IsDataStandardized
                temp_unit = '(Z-score)';
                pres_unit = '(Z-score)';
            else
                temp_unit = '(°C)';
                pres_unit = '(Pa)';
            end
            
            % PLOT 1: Time Series Plot
            cla(app.TimeSeriesAxes, 'reset');
            yyaxis(app.TimeSeriesAxes, 'left');
            plot(app.TimeSeriesAxes, app.TimeData, app.TemperatureData, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Temperature');
            ylabel(app.TimeSeriesAxes, ['Temperature ' temp_unit], 'Color', 'b');
            app.TimeSeriesAxes.YColor = 'b';
            
            yyaxis(app.TimeSeriesAxes, 'right');
            plot(app.TimeSeriesAxes, app.TimeData, app.PressureData, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Pressure');
            ylabel(app.TimeSeriesAxes, ['Pressure ' pres_unit], 'Color', 'r');
            app.TimeSeriesAxes.YColor = 'r';
            
            title(app.TimeSeriesAxes, 'Pressure & Temperature vs. Time');
            xlabel(app.TimeSeriesAxes, 'Time (sec)');
            legend(app.TimeSeriesAxes, 'Location', 'northwest');
            grid(app.TimeSeriesAxes, 'on');
            
            % CALCULATE AND DISPLAY CORRELATIONS FOR PLOT 1
            R_temp_time_matrix = corrcoef(app.TimeData, app.TemperatureData);
            R_temp_time = R_temp_time_matrix(1, 2);
            R_pres_time_matrix = corrcoef(app.TimeData, app.PressureData);
            R_pres_time = R_pres_time_matrix(1, 2);
            app.TimeSeriesCorrelationLabel.Text = sprintf('Correlations (R): Temp vs. Time = %+.4f, Pressure vs. Time = %+.4f', R_temp_time, R_pres_time);
            
            % PLOT 2: Pressure vs. Temperature Plot
            cla(app.PressureVsTempAxes, 'reset');
            plot(app.PressureVsTempAxes, app.TemperatureData, app.PressureData, 'k.', 'MarkerSize', 10, 'DisplayName', 'Data');
            title(app.PressureVsTempAxes, 'Pressure vs. Temperature');
            xlabel(app.PressureVsTempAxes, ['Temperature ' temp_unit]);
            ylabel(app.PressureVsTempAxes, ['Pressure ' pres_unit]);
            grid(app.PressureVsTempAxes, 'on');
            legend(app.PressureVsTempAxes, 'show');
            
            % CALCULATE AND DISPLAY CORRELATION FOR PLOT 2
            R_pres_temp_matrix = corrcoef(app.TemperatureData, app.PressureData);
            R_pres_temp = R_pres_temp_matrix(1, 2);
            app.PressureVsTempCorrelationLabel.Text = sprintf('Correlation (R): %+.4f', R_pres_temp);
            
            % PLOT 3: Temperature Histogram
            cla(app.TemperatureHistogramAxes, 'reset');
            histogram(app.TemperatureHistogramAxes, app.TemperatureData);
            title(app.TemperatureHistogramAxes, 'Temperature Distribution');
            xlabel(app.TemperatureHistogramAxes, ['Temperature ' temp_unit]);
            ylabel(app.TemperatureHistogramAxes, 'Frequency');
            grid(app.TemperatureHistogramAxes, 'on');
            
            % PLOT 4: Temperature Box Plot
            cla(app.TemperatureBoxPlotAxes, 'reset');
            boxchart(app.TemperatureBoxPlotAxes, app.TemperatureData, 'Orientation', 'horizontal');
            title(app.TemperatureBoxPlotAxes, 'Temperature Box Plot');
            xlabel(app.TemperatureBoxPlotAxes, ['Temperature ' temp_unit]);
            
            % PLOT 5: Pressure Histogram
            cla(app.PressureHistogramAxes, 'reset');
            histogram(app.PressureHistogramAxes, app.PressureData);
            title(app.PressureHistogramAxes, 'Pressure Distribution');
            xlabel(app.PressureHistogramAxes, ['Pressure ' pres_unit]);
            ylabel(app.PressureHistogramAxes, 'Frequency');
            grid(app.PressureHistogramAxes, 'on');
            
            % PLOT 6: Pressure Box Plot
            cla(app.PressureBoxPlotAxes, 'reset');
            boxchart(app.PressureBoxPlotAxes, app.PressureData, 'Orientation', 'horizontal');
            title(app.PressureBoxPlotAxes, 'Pressure Box Plot');
            xlabel(app.PressureBoxPlotAxes, ['Pressure ' pres_unit]);
            
            % Temperature Statistics
            temp_stats_text = {
                sprintf('Mean: %.2f', mean(app.TemperatureData)), ...
                sprintf('Median: %.2f', median(app.TemperatureData)), ...
                sprintf('Std Dev: %.2f', std(app.TemperatureData)), ...
                sprintf('Min: %.2f', min(app.TemperatureData)), ...
                sprintf('Max: %.2f', max(app.TemperatureData))
                };
            app.TemperatureStatisticsLabel.Text = temp_stats_text;
            
            % Pressure Statistics
            pres_stats_text = {
                sprintf('Mean: %.2f', mean(app.PressureData)), ...
                sprintf('Median: %.2f', median(app.PressureData)), ...
                sprintf('Std Dev: %.2f', std(app.PressureData)), ...
                sprintf('Min: %.2f', min(app.PressureData)), ...
                sprintf('Max: %.2f', max(app.PressureData))
                };
            app.PressureStatisticsLabel.Text = pres_stats_text;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide all controls until data is loaded
            app.SensitivitySlider.Visible = 'off';
            app.MultiplierLabel.Visible = 'off';
            app.AnalysisTargetGroup.Visible = 'off';
            app.IdentifyOutliersButton.Visible = 'off';
            app.RemoveOutliersButton.Visible = 'off';
            app.WinsorizeOutliersButton.Visible = 'off';
            app.StandardizeDataButton.Visible = 'off';
            app.ExportDataButton.Visible = 'off';
            
            % Hide smoothing controls
            app.SmoothDataButton.Visible = 'off';
            app.SmoothingWindowSlider.Visible = 'off';
            app.SmoothingWindowLabel.Visible = 'off';
            
            % Hide plots
            app.TimeSeriesAxes.Visible = 'off';
            app.PressureVsTempAxes.Visible = 'off';
            app.TimeSeriesCorrelationLabel.Visible = 'off';
            app.PressureVsTempCorrelationLabel.Visible = 'off';
            app.TemperatureHistogramAxes.Visible = 'off';
            app.TemperatureBoxPlotAxes.Visible = 'off';
            app.PressureHistogramAxes.Visible = 'off';
            app.PressureBoxPlotAxes.Visible = 'off';
            app.TemperatureStatisticsLabel.Visible = 'off';
            app.PressureStatisticsLabel.Visible = 'off';
            
            % Clear labels
            app.TimeSeriesCorrelationLabel.Text = '';
            app.PressureVsTempCorrelationLabel.Text = '';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*.csv', 'Select a Reactor Data CSV file');
            
            if isequal(fileName, 0)
                return;
            end
            
            fullPath = fullfile(filePath, fileName);
            
            try
                opts = detectImportOptions(fullPath);
                colNames = opts.VariableNames;
                timeColIdx = find(contains(colNames, 'Time', 'IgnoreCase', true), 1);
                tempColIdx = find(contains(colNames, 'Temp', 'IgnoreCase', true), 1);
                pressureColIdx = find(contains(colNames, 'Pressure', 'IgnoreCase', true), 1);
                
                if isempty(timeColIdx) || isempty(tempColIdx) || isempty(pressureColIdx)
                    uialert(app.UIFigure, 'Selected file must have columns containing "Time", "Temp", and "Pressure".', 'Invalid File Format');
                    return;
                end
                
                opts.VariableTypes{timeColIdx} = 'char';
                opts.VariableTypes{tempColIdx} = 'char';
                opts.VariableTypes{pressureColIdx} = 'char';
                
                data = readtable(fullPath, opts);
                
                rawTimeData = data{:, timeColIdx};
                rawTempData = data{:, tempColIdx};
                rawPressureData = data{:, pressureColIdx};
                
                numericTimeData = str2double(rawTimeData);
                numericTempData = str2double(rawTempData);
                numericPressureData = str2double(rawPressureData);
                
                isTimeCorrupted = any(isnan(numericTimeData));
                isTempCorrupted = any(isnan(numericTempData));
                isPressureCorrupted = any(isnan(numericPressureData));
                
                if isTimeCorrupted || isTempCorrupted || isPressureCorrupted
                    selection = uiconfirm(app.UIFigure, ...
                        'Corrupted or missing data detected (e.g., "ERROR", NaN). Would you like to clean the data using linear interpolation?', ...
                        'Data Cleaning Required', ...
                        'Options', {'Yes, Clean Data', 'No, Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
                    
                    if strcmp(selection, 'Yes, Clean Data')
                        app.TimeData = fillmissing(numericTimeData(:), 'linear');
                        app.TemperatureData = fillmissing(numericTempData(:), 'linear');
                        app.PressureData = fillmissing(numericPressureData(:), 'linear');
                        
                        uialert(app.UIFigure, 'Data has been successfully cleaned.', 'Cleaning Complete', 'Icon', 'success');
                    else
                        uialert(app.UIFigure, 'Data loading cancelled. Cannot plot corrupted data.', 'Operation Cancelled');
                        return;
                    end
                else
                    app.TimeData = numericTimeData;
                    app.TemperatureData = numericTempData;
                    app.PressureData = numericPressureData;
                end
                
                % Reset all processing states on new data load
                app.IsDataStandardized = false;
                app.StandardizeDataButton.Text = 'Standardize Data';
                app.IsDataSmoothed = false; % --- NEW ---
                app.SmoothDataButton.Text = 'Smooth Data'; % --- NEW ---
                
                
                % Make plots and controls visible
                app.StandardizeDataButton.Visible = 'on';
                app.IdentifyOutliersButton.Visible = 'on';
                app.ExportDataButton.Visible = 'on';
                app.SmoothDataButton.Visible = 'on';
                
                app.TimeSeriesAxes.Visible = 'on';
                app.PressureVsTempAxes.Visible = 'on';
                app.TimeSeriesCorrelationLabel.Visible = 'on';
                app.PressureVsTempCorrelationLabel.Visible = 'on';
                app.TemperatureHistogramAxes.Visible = 'on';
                app.TemperatureBoxPlotAxes.Visible = 'on';
                app.PressureHistogramAxes.Visible = 'on';
                app.PressureBoxPlotAxes.Visible = 'on';
                app.TemperatureStatisticsLabel.Visible = 'on';
                app.PressureStatisticsLabel.Visible = 'on';
                
                app.updateAllPlotsAndStats();
                
                app.FileNameLabel.Text = {'Loaded File:', strrep(fileName, '_', ' ')};
                
            catch ME
                uialert(app.UIFigure, ['Error loading file: ' ME.message], 'Error');
                app.FileNameLabel.Text = {'Loaded File:', 'Error'};
            end
        end

        % Button pushed function: IdentifyOutliersButton
        function IdentifyOutliersButtonPushed(app, event)
            if app.IsDataStandardized || app.IsDataSmoothed
                uialert(app.UIFigure, 'Please revert data to original scale and remove smoothing before identifying outliers.', 'Action Not Allowed');
                return;
            end
            
            app.OutliersVisible = ~app.OutliersVisible;
            if app.OutliersVisible
                app.updateOutliers();
                app.IdentifyOutliersButton.Text = 'Hide Outliers';
                app.SensitivitySlider.Visible = 'on';
                app.MultiplierLabel.Visible = 'on';
                app.AnalysisTargetGroup.Visible = 'on';
                app.RemoveOutliersButton.Visible = 'on';
                app.WinsorizeOutliersButton.Visible = 'on';
            else
                app.hideOutliers();
                app.hideOutlierControls();
            end
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            % When the slider value changes, just re-run the outlier detection
            app.updateOutliers();
        end

        % Selection changed function: AnalysisTargetGroup
        function AnalysisTargetGroupSelectionChanged(app, event)
            % If outliers are visible, re-run the analysis for the new target
            if app.OutliersVisible
                app.updateOutliers();
            end
        end

        % Button pushed function: RemoveOutliersButton
        function RemoveOutliersButtonPushed(app, event)
            if isempty(app.OutlierIndices)
                uialert(app.UIFigure, 'No outliers are currently identified.', 'Action Cancelled');
                return;
            end
            
            switch app.OutlierTargetData
                case 'Temperature'
                    app.TemperatureData(app.OutlierIndices) = NaN;
                    app.TemperatureData = fillmissing(app.TemperatureData, 'linear');
                case 'Pressure'
                    app.PressureData(app.OutlierIndices) = NaN;
                    app.PressureData = fillmissing(app.PressureData, 'linear');
                case 'Both'
                    app.TemperatureData(app.OutlierIndices) = NaN;
                    app.PressureData(app.OutlierIndices) = NaN;
                    app.TemperatureData = fillmissing(app.TemperatureData, 'linear');
                    app.PressureData = fillmissing(app.PressureData, 'linear');
            end
            
            app.updateAllPlotsAndStats();
            app.hideOutliers();
            app.hideOutlierControls();
            
            uialert(app.UIFigure, 'Outliers have been removed and values interpolated.', 'Outliers Removed', 'Icon', 'success');
        end

        % Button pushed function: WinsorizeOutliersButton
        function WinsorizeOutliersButtonPushed(app, event)
            if isempty(app.OutlierIndices)
                uialert(app.UIFigure, 'No outliers are currently identified.', 'Action Cancelled');
                return;
            end
            
            dataToProcess = [];
            switch app.OutlierTargetData
                case 'Temperature'
                    dataToProcess = app.TemperatureData;
                case {'Pressure', 'Both'}
                    dataToProcess = app.PressureData;
            end
            
            for i = 1:length(app.OutlierIndices)
                idx = app.OutlierIndices(i);
                outlier_val = dataToProcess(idx);
                model_val = app.OutlierModelFit(idx);
                
                if outlier_val > model_val
                    dataToProcess(idx) = model_val + app.OutlierThreshold;
                else
                    dataToProcess(idx) = model_val - app.OutlierThreshold;
                end
            end
            
            switch app.OutlierTargetData
                case 'Temperature'
                    app.TemperatureData = dataToProcess;
                case {'Pressure', 'Both'}
                    app.PressureData = dataToProcess;
            end
            
            app.updateAllPlotsAndStats();
            app.hideOutliers();
            app.hideOutlierControls();
            
            uialert(app.UIFigure, 'Outliers have been capped at the set threshold.', 'Outliers Winsorized', 'Icon', 'success');
        end

        % Button pushed function: StandardizeDataButton
        function StandardizeDataButtonPushed(app, event)
            if app.IsDataSmoothed
                uialert(app.UIFigure, 'Please remove smoothing before standardizing data.', 'Action Not Allowed');
                return;
            end
            
            app.IsDataStandardized = ~app.IsDataStandardized;
            
            if app.IsDataStandardized
                % Store the current data state before standardizing
                app.PreStandardizedTemperatureData = app.TemperatureData;
                app.PreStandardizedPressureData = app.PressureData;
                
                % Apply Z-score standardization to Temperature
                mean_temp = mean(app.TemperatureData);
                std_temp = std(app.TemperatureData);
                app.TemperatureData = (app.TemperatureData - mean_temp) / std_temp;
                
                % Apply Z-score standardization to Pressure
                mean_pres = mean(app.PressureData);
                std_pres = std(app.PressureData);
                app.PressureData = (app.PressureData - mean_pres) / std_pres;
                
                app.StandardizeDataButton.Text = 'Revert to Original';
            else
                % Revert to the data state from just before standardization
                app.TemperatureData = app.PreStandardizedTemperatureData;
                app.PressureData = app.PreStandardizedPressureData;
                app.StandardizeDataButton.Text = 'Standardize Data';
            end
            
            % Refresh all plots and hide any active outlier analysis
            app.updateAllPlotsAndStats();
            app.hideOutliers();
            app.hideOutlierControls();
        end

        % Button pushed function: SmoothDataButton
        function SmoothDataButtonPushed(app, event)
            if app.IsDataStandardized
                uialert(app.UIFigure, 'Please revert data to original scale before smoothing.', 'Action Not Allowed');
                return;
            end
            
            app.IsDataSmoothed = ~app.IsDataSmoothed;
            
            if app.IsDataSmoothed
                app.PreSmoothedTemperatureData = app.TemperatureData;
                app.PreSmoothedPressureData = app.PressureData;
                
                windowSize = round(app.SmoothingWindowSlider.Value);
                app.TemperatureData = movmean(app.PreSmoothedTemperatureData, windowSize);
                app.PressureData = movmean(app.PreSmoothedPressureData, windowSize);
                
                app.SmoothDataButton.Text = 'Revert to Unsmoothed';
                app.SmoothingWindowSlider.Visible = 'on';
                app.SmoothingWindowLabel.Visible = 'on';
            else
                app.TemperatureData = app.PreSmoothedTemperatureData;
                app.PressureData = app.PreSmoothedPressureData;
                
                app.SmoothDataButton.Text = 'Smooth Data';
                app.SmoothingWindowSlider.Visible = 'off';
                app.SmoothingWindowLabel.Visible = 'off';
            end
            
            app.updateAllPlotsAndStats();
            app.hideOutliers();
            app.hideOutlierControls();
        end

        % Value changed function: SmoothingWindowSlider
        function SmoothingWindowSliderValueChanged(app, event)
            if app.IsDataSmoothed
                windowSize = round(app.SmoothingWindowSlider.Value);
                app.TemperatureData = movmean(app.PreSmoothedTemperatureData, windowSize);
                app.PressureData = movmean(app.PreSmoothedPressureData, windowSize);
                app.updateAllPlotsAndStats();
            end
            app.SmoothingWindowLabel.Text = sprintf('Smoothing Window: %d', round(app.SmoothingWindowSlider.Value));
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if isempty(app.TimeData)
                uialert(app.UIFigure, 'No data loaded to export.', 'Action Cancelled');
                return;
            end
            
            % Create a table from the current data
            exportTable = table(app.TimeData, app.TemperatureData, app.PressureData, ...
                'VariableNames', {'Time', 'Temperature', 'Pressure'});
            
            % Define the export folder
            exportFolder = 'Exported Data';
            if ~exist(exportFolder, 'dir')
               mkdir(exportFolder);
            end
            
            % Generate a unique filename
            timestamp = char(datetime('now','Format','yyyyMMdd_HHmmss'));
            fileName = ['cleaned_data_' timestamp '.csv'];
            fullPath = fullfile(exportFolder, fileName);
            
            try
                writetable(exportTable, fullPath);
                uialert(app.UIFigure, ['Data successfully exported to: ' fullPath], 'Export Complete', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ['Error exporting file: ' ME.message], 'Error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.LoadDataButton = uibutton(app.GridLayout4, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ExportDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.IdentifyOutliersButton = uibutton(app.GridLayout4, 'push');
            app.IdentifyOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyOutliersButtonPushed, true);
            app.IdentifyOutliersButton.Text = 'Identify Outliers';
            app.MultiplierLabel = uilabel(app.GridLayout4);
            app.MultiplierLabel.Text = 'Outlier Std Div: 2.50';
            app.SensitivitySlider = uislider(app.GridLayout4);
            app.SensitivitySlider.Limits = [0.5 4];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Step = 0.5;
            app.SensitivitySlider.Value = 2.5;
            app.RemoveOutliersButton = uibutton(app.GridLayout4, 'push');
            app.RemoveOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutliersButtonPushed, true);
            app.RemoveOutliersButton.Text = 'Remove';
            app.WinsorizeOutliersButton = uibutton(app.GridLayout4, 'push');
            app.WinsorizeOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @WinsorizeOutliersButtonPushed, true);
            app.WinsorizeOutliersButton.Text = 'Winsorization';
            app.SmoothDataButton = uibutton(app.GridLayout4, 'push');
            app.SmoothDataButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothDataButtonPushed, true);
            app.SmoothDataButton.Text = 'Smooth Data';
            app.SmoothingWindowLabel = uilabel(app.GridLayout4);
            app.SmoothingWindowLabel.Text = 'Smoothing Window: 5';
            app.SmoothingWindowSlider = uislider(app.GridLayout4);
            app.SmoothingWindowSlider.Limits = [2 50];
            app.SmoothingWindowSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothingWindowSliderValueChanged, true);
            app.SmoothingWindowSlider.Step = 1;
            app.SmoothingWindowSlider.Value = 5;
            app.StandardizeDataButton = uibutton(app.GridLayout4, 'push');
            app.StandardizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @StandardizeDataButtonPushed, true);
            app.StandardizeDataButton.Text = 'Standardize Data';
            app.FileNameLabel = uilabel(app.GridLayout4);
            app.FileNameLabel.Text = '';
            app.AnalysisTargetGroup = uibuttongroup(app.GridLayout4);
            app.AnalysisTargetGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTargetGroupSelectionChanged, true);
            app.AnalysisTargetGroup.Title = 'Analysis Target Group';
            app.PressurevsTimeButton = uiradiobutton(app.AnalysisTargetGroup);
            app.PressurevsTimeButton.Text = 'Pressure vs. Time';
            app.PressurevsTimeButton.Value = true;
            app.TemperaturevsTimeButton = uiradiobutton(app.AnalysisTargetGroup);
            app.TemperaturevsTimeButton.Text = 'Temperature vs. Time';
            app.PressurevsTemperatureButton = uiradiobutton(app.AnalysisTargetGroup);
            app.PressurevsTemperatureButton.Text = 'Pressure vs. Temperature';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.BivariateAnalysisTab = uitab(app.TabGroup);
            app.BivariateAnalysisTab.Title = 'Bivariate Analysis';
            app.GridLayout2 = uigridlayout(app.BivariateAnalysisTab);
            app.TimeSeriesAxes = uiaxes(app.GridLayout2);
            title(app.TimeSeriesAxes, 'Title')
            xlabel(app.TimeSeriesAxes, 'X')
            ylabel(app.TimeSeriesAxes, 'Y')
            zlabel(app.TimeSeriesAxes, 'Z')
            app.PressureVsTempAxes = uiaxes(app.GridLayout2);
            title(app.PressureVsTempAxes, 'Title')
            xlabel(app.PressureVsTempAxes, 'X')
            ylabel(app.PressureVsTempAxes, 'Y')
            zlabel(app.PressureVsTempAxes, 'Z')
            app.TimeSeriesCorrelationLabel = uilabel(app.GridLayout2);
            app.TimeSeriesCorrelationLabel.Text = '';
            app.PressureVsTempCorrelationLabel = uilabel(app.GridLayout2);
            app.PressureVsTempCorrelationLabel.Text = '';
            app.UnivariateAnalysisTab = uitab(app.TabGroup);
            app.UnivariateAnalysisTab.Title = 'Univariate Analysis';
            app.GridLayout3 = uigridlayout(app.UnivariateAnalysisTab);
            app.TemperatureHistogramAxes = uiaxes(app.GridLayout3);
            title(app.TemperatureHistogramAxes, 'Title')
            xlabel(app.TemperatureHistogramAxes, 'X')
            ylabel(app.TemperatureHistogramAxes, 'Y')
            zlabel(app.TemperatureHistogramAxes, 'Z')
            app.TemperatureBoxPlotAxes = uiaxes(app.GridLayout3);
            title(app.TemperatureBoxPlotAxes, 'Title')
            xlabel(app.TemperatureBoxPlotAxes, 'X')
            ylabel(app.TemperatureBoxPlotAxes, 'Y')
            zlabel(app.TemperatureBoxPlotAxes, 'Z')
            app.PressureBoxPlotAxes = uiaxes(app.GridLayout3);
            title(app.PressureBoxPlotAxes, 'Title')
            xlabel(app.PressureBoxPlotAxes, 'X')
            ylabel(app.PressureBoxPlotAxes, 'Y')
            zlabel(app.PressureBoxPlotAxes, 'Z')
            app.PressureHistogramAxes = uiaxes(app.GridLayout3);
            title(app.PressureHistogramAxes, 'Title')
            xlabel(app.PressureHistogramAxes, 'X')
            ylabel(app.PressureHistogramAxes, 'Y')
            zlabel(app.PressureHistogramAxes, 'Z')
            app.TemperatureStatisticsLabel = uilabel(app.GridLayout3);
            app.TemperatureStatisticsLabel.Text = '';
            app.PressureStatisticsLabel = uilabel(app.GridLayout3);
            app.PressureStatisticsLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end